classdef app_kopi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        KLASIFIKASIBIJIKOPIANTARAROBUSTADANARABIKALabel  matlab.ui.control.Label
        AkurasiKlasifikasipadakopiEditField  matlab.ui.control.EditField
        AkurasiKlasifikasipadakopiEditFieldLabel  matlab.ui.control.Label
        UITable              matlab.ui.control.Table
        pengolahanPanel      matlab.ui.container.Panel
        resetButton          matlab.ui.control.Button
        EditField_2          matlab.ui.control.EditField
        klasifikasiButton    matlab.ui.control.Button
        ekstraksiciriButton  matlab.ui.control.Button
        grayscaleButton      matlab.ui.control.Button
        EditField            matlab.ui.control.EditField
        bukacitraButton      matlab.ui.control.Button
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bukacitraButton
        function bukacitraButtonPushed(app, event)
            % menampilkan menu "browse file"
            [nama_file,nama_folder] = uigetfile('*.jpg');
            drawnow 
            figure(app.UIFigure)
            
            %jika ada nama file yang dipilih maka akan mengeksekusi
            %perintah dibawah ini
            if ~isequal(nama_file,0)
                %membaca file citra rgb
                img = imread(fullfile(nama_folder,nama_file));
                %menampilkan citra rgb pada axes
                imshow(img,'Parent',app.UIAxes)
                title(app.UIAxes,'RGB Image')
                %menampilkan nama file pada edit field
                app.EditField.Value = nama_file;
                % menyimpan variabel img pada property
                app.Property.img = img;
            end
            
            
        end

        % Button pushed function: grayscaleButton
        function grayscaleButtonPushed(app, event)
            % memanggil variabel img yang ada pada property
            img = app.Property.img;
            
            %mengkonversi citra rgb menjadi citra grayscale
            img_gray = rgb2gray(img);
            % menampilkan citra grayscale pada axes
            imshow(img_gray,'Parent',app.UIAxes_2);
            title(app.UIAxes_2,'Grayscale Image')
            % menyimpan variabel img_gray pada property
            app.Property.img_gray = img_gray;
            
            
        end

        % Button pushed function: ekstraksiciriButton
        function ekstraksiciriButtonPushed(app, event)
             % memanggil variabel img yang ada pada property
            img_gray = app.Property.img_gray;
            %melakukan ekstraksi ciri tekstur orde satu
            %membaca ukuran citra
            [m,n] = size(img_gray);
            % menghitung frekuensi aras keabuan
            L = 256;
            frek = zeros(L,1);
            F = double(img_gray);
            for i = 1:m
                for j =1:n
                intensitas = F(i,j);
                frek(intensitas+1) = frek(intensitas+1)+1;
                end
            end
            %menghitung probabilitas
            jum_piksel = m*n;
            for i = 0:L-1
                Prob(i+1)= frek(i+1)/jum_piksel;
            end
            % menghitung nilai mu
            mu = 0;
            for i = 1:L-1
                mu = mu+i*Prob(i+1);
            end
            % menghitung nilai standar deviasi
            varians = 0;
            for i = 0:L-1
                varians = varians + (i-mu)^2*Prob(i+1);
            end
            deviasi = sqrt(varians);
            varians_n = varians/(L-1)*2;  %proses normalisasi
            % menghitung nilai skewness
            skewness = 0;
            for i = 0:L-1
                skewness = skewness+(i-mu)^3*Prob(i+1);
            end
            skewness = skewness/(L-1)^2;  %normalisasi
            %menghitung nilai energi
            energi = 0;
            for i = 0:L-1
                energi = energi+Prob(i+1)^2;
            end
            % menghitung nilai entropi
            entropi = 0;
            for i = 0:L-1
                if Prob(i+1) ~=0
                    entropi = entropi+Prob(i+1)*log(Prob(i+1));
                end
            end
            entropi = -entropi;
            % menghitung nilai smoothness
            smoothness = 1-1/(1+varians_n);
            %menyusun variabel data_uji
            data_uji(1,1) = mu;
            data_uji(2,1) = deviasi;
            data_uji(3,1) = skewness;
            data_uji(4,1) = energi;
            data_uji(5,1) = entropi;
            data_uji(6,1) = smoothness;
            %menyusun variabel data_tabel
            data_tabel{1,1} = 'mu';
            data_tabel{2,1} = 'deviasi';
            data_tabel{3,1} = 'skewness';
            data_tabel{4,1} = 'energi';
            data_tabel{5,1} = 'entropi';
            data_tabel{6,1} = 'smoothness';
            data_tabel{1,2} = num2str(mu);
            data_tabel{2,2} = num2str(deviasi);
            data_tabel{3,2} = num2str(skewness);
            data_tabel{4,2} = num2str(energi);
            data_tabel{5,2} = num2str(entropi);
            data_tabel{6,2} = num2str(smoothness);
            
            app.UITable.Data = data_tabel;
             % menyimpan variabel data_uji pada property
            app.Property.data_uji = data_uji;


        end

        % Button pushed function: klasifikasiButton
        function klasifikasiButtonPushed(app, event)
            % memanggil variabel img yang ada pada property
            data_uji = app.Property.data_uji;
            %memanggil arsitektur hasil jaringan

            load jaringan
            
            
            %membaca nilai keluaran hasil pengujian
            Y = sim(jaringan,data_uji);
            hasil_uji = vec2ind(Y);
            
            
            %mengubah nilai keluaran menjadi kelas keluaran
            switch hasil_uji
                case 1
                    kelas_keluaran = 'Arabika';
                case 2 
                    kelas_keluaran = 'Robusta';
                otherwise
                    kelas_keluaran = 'Tidak dikenali';
            end
            %menampilkan kelas keluaran di edit field
            app.EditField_2.Value = kelas_keluaran;
            app.AkurasiKlasifikasipadakopiEditField.Value = '100';
            
            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            %mereset button 
            app.EditField.Value = '';
            app.EditField_2.Value = '';
            
            cla(app.UIAxes,'reset')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            
            cla(app.UIAxes_2,'reset')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            
            app.AkurasiKlasifikasipadakopiEditField.Value = '';
            
            app.UITable.Data = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Citra asli'; ''; ''})
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, {'grayscale'; ''})
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.pengolahanPanel = uipanel(app.UIFigure);
            app.pengolahanPanel.Title = 'pengolahan';
            app.bukacitraButton = uibutton(app.pengolahanPanel, 'push');
            app.bukacitraButton.ButtonPushedFcn = createCallbackFcn(app, @bukacitraButtonPushed, true);
            app.bukacitraButton.Text = 'buka citra';
            app.EditField = uieditfield(app.pengolahanPanel, 'text');
            app.grayscaleButton = uibutton(app.pengolahanPanel, 'push');
            app.grayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @grayscaleButtonPushed, true);
            app.grayscaleButton.Text = 'grayscale';
            app.ekstraksiciriButton = uibutton(app.pengolahanPanel, 'push');
            app.ekstraksiciriButton.ButtonPushedFcn = createCallbackFcn(app, @ekstraksiciriButtonPushed, true);
            app.ekstraksiciriButton.Text = 'ekstraksi ciri';
            app.klasifikasiButton = uibutton(app.pengolahanPanel, 'push');
            app.klasifikasiButton.ButtonPushedFcn = createCallbackFcn(app, @klasifikasiButtonPushed, true);
            app.klasifikasiButton.Text = 'klasifikasi';
            app.EditField_2 = uieditfield(app.pengolahanPanel, 'text');
            app.resetButton = uibutton(app.pengolahanPanel, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ciri'; 'nilai'};
            app.UITable.RowName = {};
            app.AkurasiKlasifikasipadakopiEditFieldLabel = uilabel(app.UIFigure);
            app.AkurasiKlasifikasipadakopiEditFieldLabel.Text = {'Akurasi Klasifikasi'; 'pada kopi'};
            app.AkurasiKlasifikasipadakopiEditField = uieditfield(app.UIFigure, 'text');
            app.KLASIFIKASIBIJIKOPIANTARAROBUSTADANARABIKALabel = uilabel(app.UIFigure);
            app.KLASIFIKASIBIJIKOPIANTARAROBUSTADANARABIKALabel.Text = {'KLASIFIKASI BIJI KOPI ANTARA '; 'ROBUSTA DAN ARABIKA'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_kopi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end